classdef Commercial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LatStartEditFieldLabel       matlab.ui.control.Label
        LatStartEditField            matlab.ui.control.NumericEditField
        LonStartEditFieldLabel       matlab.ui.control.Label
        LonStartEditField            matlab.ui.control.NumericEditField
        LatEndEditFieldLabel         matlab.ui.control.Label
        LatEndEditField              matlab.ui.control.NumericEditField
        LonEndEditFieldLabel         matlab.ui.control.Label
        LonEndEditField              matlab.ui.control.NumericEditField
        CalculateButton              matlab.ui.control.Button
        RoutePanel                   matlab.ui.container.Panel
        RoutePanel_2                 matlab.ui.container.Panel
        CruisingSpeedEditFieldLabel  matlab.ui.control.Label
        CruisingSpeedEditField       matlab.ui.control.NumericEditField
        FuelBurnEditFieldLabel       matlab.ui.control.Label
        FuelBurnEditField            matlab.ui.control.NumericEditField
        ETALabel                     matlab.ui.control.Label
        FuelBurnGalhLabel            matlab.ui.control.Label
        TripDistanceinnmLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LatStartEditField
        function LatStartEditFieldValueChanged(app, event)
            value = app.LatStartEditField.Value;
            
        end

        % Value changed function: LonStartEditField
        function LonStartEditFieldValueChanged(app, event)
            value = app.LonStartEditField.Value;
            
        end

        % Value changed function: LatEndEditField
        function LatEndEditFieldValueChanged(app, event)
            value = app.LatEndEditField.Value;
            
        end

        % Value changed function: LonEndEditField
        function LonEndEditFieldValueChanged(app, event)
            value = app.LonEndEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            latS = app.LatStartEditField.Value;
            latE = app.LatEndEditField.Value;
            lonS = app.LonStartEditField.Value;
            lonE = app.LonEndEditField.Value;
            cs = app.CruisingSpeedEditField.Value;
            fb = app.FuelBurnEditField.Value;
            
            initial = [latS lonS];
            final = [latE lonE];
            
            [d,latM, lonM,duration,Predictedfuel] = Commercial_IN(initial,final,fb,cs);
            dis = sprintf("Trip Distanace: %g",d);
            app.TripDistanceinnmLabel.Text = dis;
            
            duration = sprintf("ETA(Hours): %g",duration);
            app.ETALabel.Text = duration;
            
            Predictedfuel = sprintf("Fuel Burn(Gal/h): %g",Predictedfuel);
            app.FuelBurnGalhLabel.Text = Predictedfuel;
            drawnow


            gx = geoaxes(app.RoutePanel);
            geoplot(gx,[latS latE],[lonS lonE],'g-*')
            geobasemap(gx,'colorterrain');
            
            %geoglobe(app.RoutePanel_2);
            load coastlines
            lat = [app.LatStartEditField.Value app.LatEndEditField.Value];
            lon = [app.LonStartEditField.Value app.LonEndEditField.Value];
            uif = geoglobe(app.RoutePanel_2);
            [latI,lonI] = interpm(lat,lon,0.1,'gc');
            geoplot3(uif,latI,lonI,[],'y','LineWidth',2)
            
        end

        % Value changed function: CruisingSpeedEditField
        function CruisingSpeedEditFieldValueChanged(app, event)
            value = app.CruisingSpeedEditField.Value;
            
        end

        % Value changed function: FuelBurnEditField
        function FuelBurnEditFieldValueChanged(app, event)
            value = app.FuelBurnEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LatStartEditFieldLabel = uilabel(app.UIFigure);
            app.LatStartEditFieldLabel.Text = 'Lat Start';
            app.LatStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatStartEditField.ValueChangedFcn = createCallbackFcn(app, @LatStartEditFieldValueChanged, true);
            app.LonStartEditFieldLabel = uilabel(app.UIFigure);
            app.LonStartEditFieldLabel.Text = 'Lon Start';
            app.LonStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.LonStartEditField.ValueChangedFcn = createCallbackFcn(app, @LonStartEditFieldValueChanged, true);
            app.LatEndEditFieldLabel = uilabel(app.UIFigure);
            app.LatEndEditFieldLabel.Text = 'Lat End';
            app.LatEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatEndEditField.ValueChangedFcn = createCallbackFcn(app, @LatEndEditFieldValueChanged, true);
            app.LonEndEditFieldLabel = uilabel(app.UIFigure);
            app.LonEndEditFieldLabel.Text = 'Lon End';
            app.LonEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.LonEndEditField.ValueChangedFcn = createCallbackFcn(app, @LonEndEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RoutePanel = uipanel(app.UIFigure);
            app.RoutePanel.Title = 'Route';
            app.RoutePanel_2 = uipanel(app.UIFigure);
            app.RoutePanel_2.Title = 'Route';
            app.CruisingSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.CruisingSpeedEditFieldLabel.Text = 'Cruising Speed';
            app.CruisingSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.CruisingSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @CruisingSpeedEditFieldValueChanged, true);
            app.FuelBurnEditFieldLabel = uilabel(app.UIFigure);
            app.FuelBurnEditFieldLabel.Text = 'Fuel Burn';
            app.FuelBurnEditField = uieditfield(app.UIFigure, 'numeric');
            app.FuelBurnEditField.ValueChangedFcn = createCallbackFcn(app, @FuelBurnEditFieldValueChanged, true);
            app.ETALabel = uilabel(app.UIFigure);
            app.ETALabel.Text = 'ETA: ';
            app.FuelBurnGalhLabel = uilabel(app.UIFigure);
            app.FuelBurnGalhLabel.Text = 'Fuel Burn(Gal/h): ';
            app.TripDistanceinnmLabel = uilabel(app.UIFigure);
            app.TripDistanceinnmLabel.Text = 'Trip Distance(in nm): ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Commercial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end